classdef G23_ForwardInverseControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        YawSpinner              matlab.ui.control.Spinner
        YawSpinnerLabel         matlab.ui.control.Label
        PitchSpinner            matlab.ui.control.Spinner
        PitchSpinnerLabel       matlab.ui.control.Label
        RollSpinner             matlab.ui.control.Spinner
        RollSpinnerLabel        matlab.ui.control.Label
        ZSpinner                matlab.ui.control.Spinner
        ZSpinnerLabel           matlab.ui.control.Label
        YSpinner                matlab.ui.control.Spinner
        YSpinnerLabel           matlab.ui.control.Label
        XSpinner                matlab.ui.control.Spinner
        XSpinnerLabel           matlab.ui.control.Label
        InverseKinematicsLabel  matlab.ui.control.Label
        Theta6Slider            matlab.ui.control.Slider
        Theta6SliderLabel       matlab.ui.control.Label
        Theta5Slider            matlab.ui.control.Slider
        Theta5SliderLabel       matlab.ui.control.Label
        Theta4Slider            matlab.ui.control.Slider
        Theta4SliderLabel       matlab.ui.control.Label
        Theta3Slider            matlab.ui.control.Slider
        Theta3SliderLabel       matlab.ui.control.Label
        Theta2Slider            matlab.ui.control.Slider
        Theta2SliderLabel       matlab.ui.control.Label
        ForwardKinematicsLabel  matlab.ui.control.Label
        Theta1Slider            matlab.ui.control.Slider
        Theta1SliderLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        RDK % Description
        station % Description
        robot % Description
    end
    
    methods (Access = private)
        
        function forward(app)
            % read values
            theta1 = app.Theta1Slider.Value;
            theta2 = app.Theta2Slider.Value;
            theta3 = app.Theta3Slider.Value;
            theta4 = app.Theta4Slider.Value;
            theta5 = app.Theta5Slider.Value;
            theta6 = app.Theta6Slider.Value;
            
            joints = [theta1, theta2, theta3, theta4, theta5, theta6];
            
            % solve forward kinematics
            pose = app.robot.SolveFK(joints);
            
            % convert transformation matrix to X, Y, Z and yaw, pitch, roll
            [x, y, z, roll, pitch, yaw] = app.matrixToParams(pose);
            
            % set values
            app.RollSpinner.Value = round(roll, 2);
            app.PitchSpinner.Value = round(pitch, 2);
            app.YawSpinner.Value = round(yaw, 2);
            app.XSpinner.Value = x;
            app.YSpinner.Value = y;
            app.ZSpinner.Value = z;
            
        end
        
        function inverse(app)
            % read values
            r = app.RollSpinner.Value;
            p = app.PitchSpinner.Value;
            yaw = app.YawSpinner.Value;
            x = app.XSpinner.Value;
            y = app.YSpinner.Value;
            z = app.ZSpinner.Value;
            
            % convert X, Y, Z and yaw, pitch, roll to transformation matrix
            T = app.paramsToMatrix(x, y, z, r, p, yaw);
            
            % solve inverse kinematics
            joints = app.robot.SolveIK(T);
            
            % set values
            if(isempty(joints))
                % refresh params if not updated
                % solve forward kinematics
                theta1 = app.Theta1Slider.Value;
                theta2 = app.Theta2Slider.Value;
                theta3 = app.Theta3Slider.Value;
                theta4 = app.Theta4Slider.Value;
                theta5 = app.Theta5Slider.Value;
                theta6 = app.Theta6Slider.Value;
                
                joints = [theta1, theta2, theta3, theta4, theta5, theta6];
                
                pose = app.robot.SolveFK(joints);
                
                % convert transformation matrix to X, Y, Z and yaw, pitch, roll
                [x, y, z, roll, pitch, yaw] = app.matrixToParams(pose);
                
                % set values
                app.RollSpinner.Value = round(roll, 2);
                app.PitchSpinner.Value = round(pitch, 2);
                app.YawSpinner.Value = round(yaw, 2);
                app.XSpinner.Value = x;
                app.YSpinner.Value = y;
                app.ZSpinner.Value = z;
            else
                app.Theta1Slider.Value = joints(1);
                app.Theta2Slider.Value = joints(2);
                app.Theta3Slider.Value = joints(3);
                app.Theta4Slider.Value = joints(4);
                app.Theta5Slider.Value = joints(5);
                app.Theta6Slider.Value = joints(6);
            end
        end
        
        function T = paramsToMatrix(~, x, y, z, roll, pitch, yaw)
            % Convert degrees to radians
            roll = deg2rad(roll);
            pitch = deg2rad(pitch);
            yaw = deg2rad(yaw);
            
            % Rotation matrices around X, Y, Z axes
            R_x = [1 0 0; 
                   0 cos(roll) -sin(roll); 
                   0 sin(roll) cos(roll)];
        
            R_y = [cos(pitch) 0 sin(pitch); 
                   0 1 0; 
                   -sin(pitch) 0 cos(pitch)];
        
            R_z = [cos(yaw) -sin(yaw) 0; 
                   sin(yaw) cos(yaw) 0; 
                   0 0 1];
        
            % Combined rotation matrix
            R = R_z * R_y * R_x;
        
            % Transformation matrix
            T = [R [x; y; z]; 
                 0 0 0 1];
        end
        
        function [x, y, z, roll, pitch, yaw] = matrixToParams(~, T)
            % Extract translation
            x = T(1, 4);
            y = T(2, 4);
            z = T(3, 4);
        
            % Extract rotation matrix
            R = T(1:3, 1:3);
            
            % Compute roll, pitch, yaw from rotation matrix
            pitch = atan2(-R(3, 1), sqrt(R(3, 2)^2 + R(3, 3)^2));
            if cos(pitch) > 1e-6  % Check for gimbal lock condition
                roll = atan2(R(3, 2), R(3, 3));
                yaw = atan2(R(2, 1), R(1, 1));
            else
                roll = 0;
                yaw = atan2(-R(1, 2), R(2, 2));
            end
        
            % Convert radians to degrees
            roll = rad2deg(roll);
            pitch = rad2deg(pitch);
            yaw = rad2deg(yaw);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('C:\RoboDK\RoboDK\Matlab');
            app.RDK = Robolink;
            
            % Load the station
            path = 'E:\6th sem\EC6090 Robotics and Automation\Project\Matlab\';
            app.station = app.RDK.AddFile([path, 'G23_Project.rdk']);
            
            if ~app.station.Valid()
                path = 'E:\6th sem\EC6090 Robotics and Automation\Project\Matlab\';
                app.station = app.RDK.AddFile([path, 'G23_Project.rdk']);
        
                if ~app.station.Valid()
                    app.RDK.ShowMessage(sprintf('Project Not Found'));
                    delete(app);
                    return
                end
            end
            
            app.robot = app.RDK.Item('ABB CRB 1300-11/0.9', app.RDK.ITEM_TYPE_ROBOT);
            app.RDK.setSimulationSpeed(50);
            app.robot.setPoseFrame(app.robot.Parent());
            
            joints = app.robot.Joints();
            
            % load values
            app.Theta1Slider.Value = joints(1);
            app.Theta2Slider.Value = joints(2);
            app.Theta3Slider.Value = joints(3);
            app.Theta4Slider.Value = joints(4);
            app.Theta5Slider.Value = joints(5);
            app.Theta6Slider.Value = joints(6);
            
            % solve forward kinematics
            pose = app.robot.SolveFK(joints);
            
            % convert transformation matrix to X, Y, Z and yaw, pitch, roll
            [x, y, z, roll, pitch, yaw] = app.matrixToParams(pose);
            
            % set values
            app.RollSpinner.Value = round(roll, 2); 
            app.PitchSpinner.Value = round(pitch, 2); 
            app.YawSpinner.Value = round(yaw, 2); 
            app.XSpinner.Value = x;
            app.YSpinner.Value = y;
            app.ZSpinner.Value = z;
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
            app.forward();
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
            app.forward();
        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
            app.forward();
        end

        % Value changed function: Theta4Slider
        function Theta4SliderValueChanged(app, event)
            app.forward();
        end

        % Value changed function: Theta5Slider
        function Theta5SliderValueChanged(app, event)
            app.forward();
        end

        % Value changed function: Theta6Slider
        function Theta6SliderValueChanged(app, event)
            app.forward();
        end

        % Value changed function: YawSpinner
        function YawSpinnerValueChanged(app, event)
            app.inverse();
        end

        % Value changed function: PitchSpinner
        function PitchSpinnerValueChanged(app, event)
            app.inverse();
        end

        % Value changed function: RollSpinner
        function RollSpinnerValueChanged(app, event)
            app.inverse();
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            app.inverse();
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            app.inverse();
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            app.inverse();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta 1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.ForwardKinematicsLabel = uilabel(app.UIFigure);
            app.ForwardKinematicsLabel.Text = 'Forward Kinematics';
            app.Theta2SliderLabel = uilabel(app.UIFigure);
            app.Theta2SliderLabel.Text = 'Theta 2';
            app.Theta2Slider = uislider(app.UIFigure);
            app.Theta2Slider.Limits = [-100 130];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = 'Theta 3';
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.Limits = [-210 65];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Theta4SliderLabel = uilabel(app.UIFigure);
            app.Theta4SliderLabel.Text = 'Theta 4';
            app.Theta4Slider = uislider(app.UIFigure);
            app.Theta4Slider.Limits = [-230 230];
            app.Theta4Slider.ValueChangedFcn = createCallbackFcn(app, @Theta4SliderValueChanged, true);
            app.Theta5SliderLabel = uilabel(app.UIFigure);
            app.Theta5SliderLabel.Text = 'Theta 5';
            app.Theta5Slider = uislider(app.UIFigure);
            app.Theta5Slider.Limits = [-130 130];
            app.Theta5Slider.ValueChangedFcn = createCallbackFcn(app, @Theta5SliderValueChanged, true);
            app.Theta6SliderLabel = uilabel(app.UIFigure);
            app.Theta6SliderLabel.Text = 'Theta 6';
            app.Theta6Slider = uislider(app.UIFigure);
            app.Theta6Slider.Limits = [-242 242];
            app.Theta6Slider.ValueChangedFcn = createCallbackFcn(app, @Theta6SliderValueChanged, true);
            app.InverseKinematicsLabel = uilabel(app.UIFigure);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics';
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.UIFigure);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.UIFigure);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.RollSpinnerLabel = uilabel(app.UIFigure);
            app.RollSpinnerLabel.Text = 'Roll';
            app.RollSpinner = uispinner(app.UIFigure);
            app.RollSpinner.ValueChangedFcn = createCallbackFcn(app, @RollSpinnerValueChanged, true);
            app.PitchSpinnerLabel = uilabel(app.UIFigure);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.PitchSpinner = uispinner(app.UIFigure);
            app.PitchSpinner.ValueChangedFcn = createCallbackFcn(app, @PitchSpinnerValueChanged, true);
            app.YawSpinnerLabel = uilabel(app.UIFigure);
            app.YawSpinnerLabel.Text = 'Yaw';
            app.YawSpinner = uispinner(app.UIFigure);
            app.YawSpinner.ValueChangedFcn = createCallbackFcn(app, @YawSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = G23_ForwardInverseControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end